classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UITable2        matlab.ui.control.Table
        AddRowButton    matlab.ui.control.Button
        SaveDataButton  matlab.ui.control.Button
        UITable         matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                              
                numRows = 10;
                app.UITable.Data          = repmat({''},numRows,3);            
                app.UITable.ColumnName    = {'Bus','S (MVA)','I_max(A)'};                         
                app.UITable.ColumnEditable = true(1,3);  

                app.UITable2.Data          = repmat({''},numRows,5);            
                app.UITable2.ColumnName    = {'Breaker','Breaker t_op (Cycles)','CT Primary','CT second','IRelay'};                         
                app.UITable2.ColumnEditable = true(1,5);  
      
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
                rawData = app.UITable.Data;
                tableData = cellfun(@str2double, rawData);
                rowsToDelete = all(isnan(tableData), 2);
                tableData(rowsToDelete, :) = [];
            

                rawData2 = app.UITable2.Data;
                tableData2 = cellfun(@str2double, rawData2);
                rowsToDelete2 = all(isnan(tableData2), 2);
                tableData2(rowsToDelete2, :) = [];

            assignin('base', 'tableDataFromApp', tableData);
            assignin('base', 'tableDataFromApp2', tableData2);
            uiresume(app.UIFigure); 
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            oldData = app.UITable.Data;
           nCols = size(oldData,2);
           newRow = repmat({''},1,nCols);
           app.UITable.Data = [oldData; newRow];
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'From Bus'; 'To Bus'; 'Type of con'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true];
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.AddRowButton = uibutton(app.UIFigure, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end