classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Image           matlab.ui.control.Image
        AddRowButton    matlab.ui.control.Button
        SaveDataButton  matlab.ui.control.Button
        UITable         matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
                    answer = inputdlg({'Enter required number of rows(approximately):'}, ...
                      'Number of Rows', [1 35], {'10'}); 
                 numRows = str2double(answer{1});
                app.UITable.Data          = repmat({''},numRows,7);
            
                app.UITable.ColumnName    = {'From Bus','To Bus','Connection type','zero seq X','Pos. seq X','Neg seq X','Neutral seq X'};
                         
                app.UITable.ColumnEditable = true(1,7);  
      
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
                rawData = app.UITable.Data;
                tableData = cellfun(@str2double, rawData);
                rowsToDelete = all(isnan(tableData), 2);
                tableData(rowsToDelete, :) = [];
            assignin('base', 'tableDataFromApp', tableData);
            delete(app);
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            oldData = app.UITable.Data;
           nCols = size(oldData,2);
           newRow = repmat({''},1,nCols);
           app.UITable.Data = [oldData; newRow];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'From Bus'; 'To Bus'; 'Type of con'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true];
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.AddRowButton = uibutton(app.UIFigure, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end